classdef DRTanalyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DRTAnalyzerUIFigure            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportImpedanceDataMenu        matlab.ui.container.Menu
        ImportDRTDataMenu              matlab.ui.container.Menu
        SaveLoadPeakInfoMenu           matlab.ui.container.Menu
        SaveMenu_2                     matlab.ui.container.Menu
        PeakInfoMenu                   matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        AllPeakInfoMenu                matlab.ui.container.Menu
        PeakAnalysisPanel              matlab.ui.container.Panel
        TabGroup6                      matlab.ui.container.TabGroup
        DRTrawTab                      matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        DRTevaluatedTab                matlab.ui.container.Tab
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        DRTCPEexponentTab              matlab.ui.container.Tab
        PeaknoDropDown                 matlab.ui.control.DropDown
        PeaknoDropDownLabel            matlab.ui.control.Label
        UIAxes_5                       matlab.ui.control.UIAxes
        TabGroup3                      matlab.ui.container.TabGroup
        PeakparametersTab              matlab.ui.container.Tab
        nLabel                         matlab.ui.control.Label
        RLabel                         matlab.ui.control.Label
        log_10tauLabel                 matlab.ui.control.Label
        PeakLabel                      matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        OtherparametersTab             matlab.ui.container.Tab
        ValueLabel                     matlab.ui.control.Label
        ParameterLabel                 matlab.ui.control.Label
        UITable2                       matlab.ui.control.Table
        DataNoDropDown_2               matlab.ui.control.DropDown
        DataNoDropDown_2Label          matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        PeakanalysispreprocessingTab   matlab.ui.container.Tab
        SelectCandidatesallButton      matlab.ui.control.Button
        SwitchtopeakviewButton         matlab.ui.control.StateButton
        ChangeboundsLabel              matlab.ui.control.Label
        ChangepeaksLabel               matlab.ui.control.Label
        SelectCandidatesButton         matlab.ui.control.Button
        DeleteboundsButton             matlab.ui.control.StateButton
        AddboundsButton                matlab.ui.control.StateButton
        DeletepeaksButton              matlab.ui.control.StateButton
        AddpeaksButton                 matlab.ui.control.StateButton
        OptimisationsettingTab         matlab.ui.container.Tab
        MethodDropDown                 matlab.ui.control.DropDown
        MethodLabel                    matlab.ui.control.Label
        UseparallelcomputingCheckBox   matlab.ui.control.CheckBox
        GenerateandsaveseparatefiguresCheckBox  matlab.ui.control.CheckBox
        SelecttoevaluateEditField      matlab.ui.control.EditField
        SelecttooptimizeLabel          matlab.ui.control.Label
        EvaluateselectedButton         matlab.ui.control.Button
        EvaluationtimelimitsEditField  matlab.ui.control.NumericEditField
        EvaluationtimelimitsLabel      matlab.ui.control.Label
        SaveNameEditField              matlab.ui.control.EditField
        SaveNameLabel                  matlab.ui.control.Label
        DRTDataNoDropDown              matlab.ui.control.DropDown
        DRTDataNoDropDownLabel         matlab.ui.control.Label
        TabGroup4                      matlab.ui.container.TabGroup
        ParameterexportTab             matlab.ui.container.Tab
        SavealldatasetsCheckBox        matlab.ui.control.CheckBox
        SaveparameterstoworkspaceButton  matlab.ui.control.Button
        VariablenameEditField          matlab.ui.control.EditField
        VariablenameLabel              matlab.ui.control.Label
        DRTAnalysisPanel               matlab.ui.container.Panel
        TabGroup5                      matlab.ui.container.TabGroup
        LoadedfilesTab                 matlab.ui.container.Tab
        FileloadingoptionButtonGroup   matlab.ui.container.ButtonGroup
        BiologictxtButton              matlab.ui.control.RadioButton
        mfileButton                    matlab.ui.control.RadioButton
        UITable5                       matlab.ui.control.Table
        ImpedanceTab                   matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        SetvalueTab_2                  matlab.ui.container.Tab
        UITable4                       matlab.ui.control.Table
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ThevalueisusedtocontroltheregularizationeffectLabel  matlab.ui.control.Label
        SetvalueTab                    matlab.ui.container.Tab
        TheLabel                       matlab.ui.control.Label
        UITable3                       matlab.ui.control.Table
        Z_capfrac1jomegaCphiLabel      matlab.ui.control.Label
        ThephiLabel                    matlab.ui.control.Label
        ImpedanceDataNoDropDown        matlab.ui.control.DropDown
        ImpedanceDataNoDropDownLabel   matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        DRTTab                         matlab.ui.container.Tab
        RunDRTButton                   matlab.ui.control.Button
        ChoosedataforcalculationTab    matlab.ui.container.Tab
        ClearfittingButton             matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        ApplytoallCheckBox             matlab.ui.control.CheckBox
        ClearallCheckBox               matlab.ui.control.CheckBox
        FinishButton                   matlab.ui.control.Button
        startchoosingButton            matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        Menu_2                         matlab.ui.container.Menu
        Menu2_2                        matlab.ui.container.Menu
    end


    properties (Access = private)
        data % Description
        file_txt % file name for .txt files
        n_data
        dy2
        dy2_grad
        data_normalized
        currentSet
        peak_info
        check
        abs_path
        data_1 % Description
        data_2 % Description
        data_3 % Description
        data_total % Description
        n_z_data
        z_data
        DRT_data % Description
        DRT_RL_data % inductive data
        DRT_Z_recon % DRT reconstructed impedance 
        C_DRT % DRT capacitance 
        R_ohmic % ohmic resistance
        i_runDRT % data set chosen to run DRT
        flag_runDRT % flag variable for running DRT (1=run, 0=not run yet)
        brush_data % save brushdata for each data set
        phi % phi value for non-ideal capacitance -> fractal diffusion
        h_fit % axes handle to all impedance figures
        p_fit % coefficients of linear fitting of each data set
        xy_fit % data for linear fitting
        x_sep % x values for separated peaks
        y_sep % y values for separated peaks
        y_fit % fitted DRT
        flag_evaluation % flag for whether peak analysis has been conducted
        norm_factor % normalization factor for restoration original data
        Z_peak_recon % impedance of each reconstructed peak of each data set
        no_peaks % number of peaks of each data set
        CPE_fit_param % fitting parameter for each peak of each data set
    end
    

    methods (Access = private)

    end

    methods (Access = public)
        function [] = plotCurrentSet(app)
            x = app.data{app.currentSet}(:,1);
            y = app.data{app.currentSet}(:,2);
            app.UIAxes.Title.String = ['Dataset: ', num2str(app.currentSet)];
            
            plot(app.UIAxes,x,y);
            grid(app.UIAxes,"on");

            app.UIAxes.YLim(2) = 1.05*max(y);

            if(app.SwitchtopeakviewButton.Value)
                hold(app.UIAxes,"on");
                app.plotPatches(app.currentSet);
                app.plotPeaks(app.currentSet);
                legend(app.UIAxes,['Npeaks: ', num2str(length(app.peak_info{app.currentSet}.peaks.y) )], 'Location','northeast')
                hold(app.UIAxes,"off");
            end
            
            
            plot(app.UIAxes_2, x, app.dy2{app.currentSet}./max(abs(app.dy2{app.currentSet})) + 1, 'Color', '#D95319'); 
            legend(app.UIAxes_2, 'smoothed 2nd derivative')
%             hold(app.UIAxes_2,"on");
%             plot(app.UIAxes_2, x, app.dy2_grad{app.currentSet}./max(abs(app.dy2_grad{app.currentSet})) + 1);
%             hold(app.UIAxes_2,"off");
            
            %linkaxes([app.UIAxes, app.UIAxes_2],'x'); Makes code very
            %slow.
            grid(app.UIAxes_2,"on");
        end

        function dy_sg2 = get_secondDerivative(app, i)
            [~,g_sg] = sgolay(3,15);
            p = 2;
            dt = 1;
            dy_sg2 = conv(app.data_normalized{i}(:,2), factorial(p)/(-dt)^p * g_sg(:,p+1), 'same');
        end

        function [] = normalizeDataset(app,i)
            maxY = max(app.data_normalized{i}(:,2));
            app.data_normalized{i}(:,2) = app.data_normalized{i}(:,2)/maxY;
            app.peak_info{i}.peaks.y  = app.peak_info{i}.peaks.y/maxY;
            app.peak_info{i}.bounds.y  = app.peak_info{i}.bounds.y/maxY;
            app.check.is_data_normalized(i) = true;
            
            app.dy2{i} = app.get_secondDerivative(i);
        end

        function [] = makePeakButtonsVisible(app)
            val = app.SwitchtopeakviewButton.Value;

            app.ChangeboundsLabel.Visible = val;
            app.DeleteboundsButton.Visible = val;
            app.ChangepeaksLabel.Visible = val;
            app.AddboundsButton.Visible = val;
            app.DeletepeaksButton.Visible = val;
            app.AddpeaksButton.Visible = val;
            app.SelectCandidatesButton.Visible = val;
            app.SelectCandidatesallButton.Visible = val;
        end
        function [] = peak_info_init(app,i)
            app.dy2{i} = app.get_secondDerivative(i);
            app.dy2_grad{i} = gradient(gradient(  app.data_normalized{i}(:,2)    )); 
            app.peak_info{i} = struct();

            [peaks, bounds] = app.peak_detect_p1(app.data_normalized{i}(:,1),app.data_normalized{i}(:,2));

            app.peak_info{i}.peaks = peaks;
            app.peak_info{i}.bounds = bounds;
            app.peak_info{i}.lb_ub = app.find_lb_ub(peaks,bounds);

            n_peaks = length(peaks);
            app.peak_info{i}.indices = {1:n_peaks, [], [] };
            app.peak_info{i}.tfs = {[1], [1], [1]};
        end

        function [] = plotPatches(app,j)

            nPatch = length(app.peak_info{j}.bounds.x)-1;
            myColors = cbrewer('qual','Set3',nPatch);
            
            myColors(myColors>1) = 1;


            y1 = 0;
            y2 = 1.03*max(app.data_normalized{j}(:,2));

            for i=1:nPatch
                x1 = app.peak_info{j}.bounds.x(i);
                x2 = app.peak_info{j}.bounds.x(i+1);


                Xpatch = [x1 x2 x2 x1];
                Ypatch = [y1 y1 y2 y2];

                ax = patch(app.UIAxes,Xpatch,Ypatch,myColors(i,:));%'FaceColor','red','FaceAlpha',.3);
                ax.FaceAlpha = 0.3;
            end
        end

        function [] = plotPeaks(app,j)
            plot(app.UIAxes,app.peak_info{j}.peaks.x,app.peak_info{j}.peaks.y,'o','markerfacecolor', 'b','markeredgecolor', 'b','MarkerSize',5);
        end


        function lb_ub = find_lb_ub(app, peaks, bounds)
            n_locs = length(peaks.x);
            lb_ub = zeros(n_locs, 2);

            for i=1:length(peaks.x)
                k_loc = find(bounds.x < peaks.x(i) ,1,'last');
                lb_ub(i,:) = bounds.x(k_loc:k_loc+1);
            end

        end

        function [] = delete_ith_peak(app,j,i)
            app.peak_info{j}.peaks.x(i) = [];
            app.peak_info{j}.peaks.y(i) = [];
            app.peak_info{j}.lb_ub(i,:) = [];
        end

        function [] = delete_ith_bound(app,j,i)
            n_bound = length(app.peak_info{j}.bounds.x);
            if (i==1)
                app.peak_info{j}.bounds.x(i) = app.data_normalized{j}(1,1);
                app.peak_info{j}.bounds.y(i) = 1;

            elseif(i==n_bound)
                app.peak_info{j}.bounds.x(i) = app.data_normalized{j}(end,1);
                app.peak_info{j}.bounds.y(i) = 1;
            else
                app.peak_info{j}.bounds.x(i) = [];
                app.peak_info{j}.bounds.y(i) = [];
            end

            app.peak_info{j}.lb_ub = app.find_lb_ub(app.peak_info{j}.peaks, app.peak_info{j}.bounds);
        end

        function [] = addPeaks(app, points)
            % points : input Nx2
            j = app.currentSet;

            app.peak_info{j}.peaks.x = [app.peak_info{j}.peaks.x; points(:,1)];
            app.peak_info{j}.peaks.y = [app.peak_info{j}.peaks.y; points(:,2)];

            [app.peak_info{j}.peaks.x, sorting_index] = sort(app.peak_info{j}.peaks.x);

            app.peak_info{j}.peaks.y = app.peak_info{j}.peaks.y(sorting_index);

            app.peak_info{j}.lb_ub = app.find_lb_ub(app.peak_info{j}.peaks, app.peak_info{j}.bounds);
        end

        function [] = deletePeaks(app, loc)
            j = app.currentSet;
            XLim = app.UIAxes.XLim;
            YLim = app.UIAxes.YLim;

            dX  = XLim(2) - XLim(1);
            dY  = YLim(2) - YLim(1);

            marg = 0.015;

            tolX = marg*dX; % Tolerance for selecting a point.
            tolY = marg*dY;

            distX = abs(app.peak_info{j}.peaks.x - loc(1));
            distY = abs(app.peak_info{j}.peaks.y - loc(2));

            dist_tot = distX + distY;

            [~,closestIndex] = min(dist_tot);

            if(distX(closestIndex)>tolX)
                return;
            end

            if(distY(closestIndex)>tolY)
                return;
            end

            app.delete_ith_peak(j, closestIndex);

        end
        function [] = addBounds(app, points)
            % points : input Nx2
            j = app.currentSet;

            app.peak_info{j}.bounds.x = [app.peak_info{j}.bounds.x; points(:,1)];
            app.peak_info{j}.bounds.y = [app.peak_info{j}.bounds.y; points(:,2)];

            [app.peak_info{j}.bounds.x, sorting_index] = sort(app.peak_info{j}.bounds.x);

            app.peak_info{j}.bounds.y = app.peak_info{j}.bounds.y(sorting_index);

            app.peak_info{j}.lb_ub = app.find_lb_ub(app.peak_info{j}.peaks, app.peak_info{j}.bounds);
        end

        function [] = deleteBounds(app, loc)
            j = app.currentSet;
            XLim = app.UIAxes.XLim;

            dX  = XLim(2) - XLim(1);

            marg = 0.015;

            tolX = marg*dX; % Tolerance for selecting a point.

            distX = abs(app.peak_info{j}.bounds.x - loc(1));

            [~,closestIndex] = min(distX);

            if(distX(closestIndex)>tolX)
                return;
            end

            app.delete_ith_bound(j, closestIndex);

        end


        function [peaks,bounds] = peak_detect_p1(app, x, y)
           [peaks,bounds]  = peak_detect_p3(x,y);
        end

        function result = check_if_optimisable(app, i_batch)
            result = true;
            if(~app.check.is_cand_selected(i_batch))
                msg = ['Candidate functions are not selected for dataset : ', num2str(i_batch), '.'];
                msgbox(msg);
                result = false;
            end
        end

        function [] = initCheck(app)
            app.check.is_cand_selected = false(1,app.n_data);
            app.check.is_data_normalized = false(1,app.n_data);

        end
        function [] = select_candidates(app, i_batch)
            n_peaks = length(app.peak_info{i_batch}.peaks.x);

            myList = {};
            for i=1:n_peaks
                myList{i} = ['Peak ', num2str(i)];
            end
            cand_names = {'Gaussian function', 'trigo function', 'nonsymmetric gaussian'};

            indices = {};
            tfs = {};

            for i=1:length(cand_names)
                prString = {'Select the peaks the candidate ', [num2str(i),': ', cand_names{i}], 'is applied: '};


                [indx,tf] = listdlg('PromptString',prString, 'ListString',myList);

                indices{i} = indx;
                tfs{i} = tf;

            end

            indices{1} = setdiff(indices{1}, indices{3});

            app.peak_info{i_batch}.indices = indices;
            app.peak_info{i_batch}.tfs = tfs;
            app.check.is_cand_selected(i_batch) = all([tfs{:}]);

        end
        
        function appStruct = getAppStruct(app)
            appStruct = struct();
            appStruct.data = app.data;
            appStruct.n_data = app.n_data;
            appStruct.data_normalized = app.data_normalized;
            appStruct.currentSet = app.currentSet;
            appStruct.peak_info = app.peak_info;
            appStruct.check  = app.check;
            appStruct.other.MaxTime = app.EvaluationtimelimitsEditField.Value;
            appStruct.other.SaveName = app.SaveNameEditField.Value;
            appStruct.other.PlotSeparateFigure = app.GenerateandsaveseparatefiguresCheckBox.Value;
            appStruct.other.norm_factor = app.norm_factor;
            
            appStruct.abs_path = app.abs_path;
            
            appStruct.other.Method = app.MethodDropDown.Value;
%             appStruct.other.ShowPlots = app.ShowplotsattheendCheckBox.Value;
        end
        
        function z_initCheck(app)
            for i = 1:app.n_z_data
                z_data_i = app.z_data{i};
                if sum(isnan(z_data_i), 'all') >= 1
                    msgbox(['NaN values in data set ', numestr(i), ' detected, please check the input data!'])
                end
            
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.abs_path = fileparts(mfilename('fullpath'));

            addpath(fullfile(app.abs_path,"ExtLib\cbrewer\cbrewer"));
            addpath(fullfile(app.abs_path,"AuxFun\"));
            addpath(fullfile(app.abs_path,"results\"))
            addpath(fullfile(app.abs_path,"data\"))
        end

        % Menu selected function: ImportDRTDataMenu
        function ImportDRTDataMenuSelected(app, event)


            [file,path] = uigetfile('*.mat');

            filepath = fullfile(path, file);
            loadedData = load(filepath);
            app.data = loadedData.data;
            app.data_normalized = app.data;

            app.n_data = length(app.data);
            app.initCheck();


            app.peak_info = cell(1, app.n_data);

            for i=1:app.n_data
                app.DRTDataNoDropDown.Items{i} = ['Dataset: ', num2str(i)];
                app.peak_info_init(i);
            end

            app.currentSet = 1;

            app.plotCurrentSet();


        end

        % Value changed function: DRTDataNoDropDown
        function DRTDataNoDropDownValueChanged(app, event)
            value = app.DRTDataNoDropDown.Value;
            
            cla(app.UIAxes_5)
            app.PeaknoDropDown.Items = {};
            app.PeaknoDropDown.Items{1} = 'N/A';

            text_opt = app.SelecttoevaluateEditField.Value;
            text_opt(text_opt=='-') = ':';            
            i_batchs = eval(['[', text_opt, ']']);
            
            if strcmp(value, 'Plot all')
                hold(app.UIAxes, 'on')
                hold(app.UIAxes_2, 'on')
                for i = 1:app.n_data
                    app.currentSet = i;
                    app.plotCurrentSet();
                end
                hold(app.UIAxes, 'off')
                hold(app.UIAxes_2, 'off')
            else
                    app.currentSet = str2num(value(find(value==' ')+1:end));
                    app.plotCurrentSet();


                    plot(app.UIAxes_3, app.data{app.currentSet}(:, 1), app.data{app.currentSet}(:, 2), '-', 'Color', '#0072BD', 'LineWidth', 1)
                    hold (app.UIAxes_3, 'on')
                    plot(app.UIAxes_3, app.x_sep{app.currentSet}, app.y_fit{app.currentSet}, '--r', 'LineWidth', 1)
                    hold (app.UIAxes_3, 'off')
                    legend(app.UIAxes_3, 'DRT', 'Fitted')


                    plot(app.UIAxes_4, app.data{app.currentSet}(:, 1), app.data{app.currentSet}(:, 2), '-', 'Color', '#0072BD', 'LineWidth', 1)
                    hold (app.UIAxes_4, 'on')
                    plot(app.UIAxes_4, app.x_sep{app.currentSet}, app.y_sep{app.currentSet}, '--r', 'LineWidth', 1)
                    hold (app.UIAxes_4, 'off')
                    legend(app.UIAxes_4, 'DRT', 'Separated peaks')

            end
            
            if ~isempty(app.data_total) && ismember(str2double(value(10:end)), i_batchs)
                ylim(app.UIAxes_4, [0 max(app.data_total{str2double(value(10:end))}(:, 2))*1.25])
                text(app.UIAxes_4, app.data_total{str2double(value(10:end))}(:, 3), min(app.data_total{str2double(value(10:end))}(:, 2)*1.2, max(app.data_total{str2double(value(10:end))}(:, 2))*1.25), ...
               arrayfun(@num2str, 1:length(app.data_total{str2double(value(10:end))}(:, 1)), 'UniformOutput', 0), 'Color', 'red' )
            end

            if ismember(app.currentSet, i_batchs) && ~isempty(app.no_peaks)
                for i=1:app.no_peaks(app.currentSet)
                    app.PeaknoDropDown.Items{i} = ['Peak: ', num2str(i)];
                end

                plot(app.UIAxes_5, real(app.Z_peak_recon{app.currentSet}(:, 1)), -imag(app.Z_peak_recon{app.currentSet}(:, 1)), '-ro', 'markerfacecolor', 'r', ...
                'MarkerEdgeColor', 'r')

            else
                app.PeaknoDropDown.Items = {};
                app.PeaknoDropDown.Items{1} = 'N/A';
            end

        end

        % Callback function
        function NormaliseCurrentDatesetButtonPushed(app, event)
            app.normalizeDataset(app.currentSet);
            app.DRTDataNoDropDownValueChanged(true);
        end

        % Callback function
        function NormaliseAllDatesetsButtonPushed(app, event)
            for i=1:app.n_data
                app.normalizeDataset(i);
            end
            app.DRTDataNoDropDownValueChanged(true);
        end

        % Value changed function: SwitchtopeakviewButton
        function SwitchtopeakviewButtonValueChanged(app, event)
            value = app.SwitchtopeakviewButton.Value;
            app.makePeakButtonsVisible();

            if(value)
                app.SwitchtopeakviewButton.Text{2} = 'normal';
            else
                app.SwitchtopeakviewButton.Text{2} = 'peak';
            end
            app.DRTDataNoDropDownValueChanged(true);
        end

        % Callback function
        function AddpeaksButton1Pushed(app, event)
            
        end

        % Window button down function: DRTAnalyzerUIFigure
        function DRTAnalyzerUIFigureWindowButtonDown(app, event)


            j = app.currentSet;

            % https://uk.mathworks.com/matlabcentral/answers/392617-how-can-i-use-ginput-in-app-designer
            loc = app.UIAxes.CurrentPoint(1,1:2); % Returns 2x3 array of points / Gets the (x,y) coordinates

            XLim = app.UIAxes.XLim;
            YLim = app.UIAxes.YLim;


            if( loc(2) < YLim(1) || loc(2) > YLim(2) || loc(1) < XLim(1) || loc(1) > XLim(2) )
                XLim2 = app.UIAxes_2.XLim;
                YLim2 = app.UIAxes_2.YLim;
                loc2 = app.UIAxes_2.CurrentPoint(1,1:2);
                
                if( loc2(2) < YLim2(1) || loc2(2) > YLim2(2) || loc2(1) < XLim2(1) || loc2(1) > XLim2(2) )
                    return;
                else
                    loc = loc2; 
                    loc(2) = interp1(app.data_normalized{j}(:,1),app.data_normalized{j}(:,2), loc(1));
                end
                
                
            end
            
            
            if(app.DeletepeaksButton.Value)
                app.deletePeaks(loc);
            elseif(app.AddpeaksButton.Value)
                app.addPeaks(loc);
            elseif(app.DeleteboundsButton.Value)
                app.deleteBounds(loc);
            elseif(app.AddboundsButton.Value)
                app.addBounds(loc);
            else
                return;
            end

            app.plotCurrentSet();


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: DeletepeaksButton
        function DeletepeaksButtonValueChanged(app, event)
            app.AddpeaksButton.Value = false;
            app.DeleteboundsButton.Value = false;
            app.AddboundsButton.Value = false;

        end

        % Value changed function: AddpeaksButton
        function AddpeaksButtonValueChanged(app, event)
            app.DeletepeaksButton.Value = false;
            app.DeleteboundsButton.Value = false;
            app.AddboundsButton.Value = false;
        end

        % Value changed function: AddboundsButton
        function AddboundsButtonValueChanged(app, event)
            app.DeletepeaksButton.Value = false;
            app.AddpeaksButton.Value = false;
            app.DeleteboundsButton.Value = false;
        end

        % Value changed function: DeleteboundsButton
        function DeleteboundsButtonValueChanged(app, event)
            app.DeletepeaksButton.Value = false;
            app.AddpeaksButton.Value = false;
            app.AddboundsButton.Value = false;
        end

        % Callback function
        function EvaluatecurrentButtonPushed(app, event)
            if(~app.check_if_optimisable(app.currentSet))
                return;
            end
            appStruct = app.getAppStruct();
            
            [app.x_sep{app.currentSet}, app.y_sep{app.currentSet}, app.y_fit{app.currentSet}] = optimiseCurrent(appStruct, app.currentSet);

        end

        % Button pushed function: SelectCandidatesButton
        function SelectCandidatesButtonPushed(app, event)
            app.select_candidates(app.currentSet);
        end

        % Button pushed function: EvaluateselectedButton
        function EvaluateselectedButtonPushed(app, event)

            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)
            app.PeaknoDropDown.Items = {};
            app.PeaknoDropDown.Items{1} = 'N/A';

            app.DataNoDropDown_2.Items = {'N/A'};
            app.DataNoDropDown_2.Value = 'N/A';

            for i = 1:app.n_data
                app.x_sep{i} = app.DRT_data{i}(:, 1);
                app.y_fit{i} = app.DRT_data{i}(:, 1)*0;
                app.y_sep{i} = app.DRT_data{i}(:, 1)*0;
            end
            
            text = app.SelecttoevaluateEditField.Value;
            text(text=='-') = ':';            
            i_batchs = eval(['[', text, ']']);
            
            if(isempty(i_batchs))
                i_batchs = 1:(app.n_data);
            end
            
            for i_batch=i_batchs
                if(~app.check_if_optimisable(i_batch))
                    return;
                end
            end
            
            appStruct = app.getAppStruct();

            flag_parallel = app.UseparallelcomputingCheckBox.Value;
            
            if(strcmpi(appStruct.other.Method, 'MultiSearch: lsqnonlin') || ...
               strcmpi(appStruct.other.Method, 'Particle Swarm Optimisation') || ...
               strcmpi(appStruct.other.Method, 'Genetic Algorithm') || ...
               ~flag_parallel)
                
                for j=1:length(i_batchs)
                    i_batch = i_batchs(j);
                    [app.x_sep{i_batch}, app.y_sep{i_batch}, app.y_fit{i_batch}] = optimiseCurrent(appStruct, i_batch);
                end         
                
            else
                parfor j=1:length(i_batchs)
                    i_batch = i_batchs(j);
                    [x_sep_parfor{j}, y_sep_parfor{j}, y_fit_parfor{j}] = optimiseCurrent(appStruct, i_batch);
                end

                for j=1:length(i_batchs)
                    app.x_sep{i_batchs(j)} = x_sep_parfor{j};
                    app.y_sep{i_batchs(j)} = y_sep_parfor{j};
                    app.y_fit{i_batchs(j)} = y_fit_parfor{j};
                end

            end

            % print parameters
            if(isempty(app.n_data))
                msgbox('Please load the data first.');
            end
            clc;
            all_param = {};
            table_data = [];
            
            text_opt = app.SelecttoevaluateEditField.Value;
            text_opt(text_opt=='-') = ':';            
            i_batchs = eval(['[', text_opt, ']']);
            
            if(isempty(i_batchs))
                i_batchs = 1:(app.n_data);
            end
            
            for i_batch=i_batchs
                if(~app.check_if_optimisable(i_batch))
                    return;
                end
            end
            
            for j=1:length(i_batchs)
                fprintf('SOC : %d\n',i_batchs(j));
                saveName = [app.SaveNameEditField.Value, num2str(i_batchs(j))];
                load(fullfile('results',[saveName,'.mat']));
                all_param{j} = printModelparam(x, cand, x_opt, cand_info,y, y_orj);
                fprintf('\n\n');
            end
                                    
            for k = 1:app.n_data
                if ismember(k, i_batchs)
                    [~, idx_opt] = ismember(k, i_batchs);
                    app.DataNoDropDown_2.Items{idx_opt} = num2str(i_batchs(idx_opt));
                    app.data_1{k} = [];
                    app.data_2{k} = [];
                    app.data_3{k} = [];
                    for i = 1:3
                        if i == 3 && ~isempty(all_param{idx_opt}{3})
                            app.data_3{k} = all_param{idx_opt}{3}(:,all_param{idx_opt}{3}(1,:)~=0);
                            app.data_3{k}(3, :) = [];
                        elseif i == 2 && ~isempty(all_param{idx_opt}{2})
                            app.data_2{k} = all_param{idx_opt}{2}(:,all_param{idx_opt}{2}(1,:)~=0);
                        elseif i == 1 && ~isempty(all_param{idx_opt}{1})
                            app.data_1{k} = all_param{idx_opt}{1}(:,all_param{idx_opt}{1}(1,:)~=0);
                        end
                    end
                                    
                    app.data_total{k} = [app.data_1{k}, app.data_2{k}, app.data_3{k}];
                    app.data_total{k} = app.data_total{k}([1:2, 4], :);
                    app.data_total{k} = app.data_total{k}';
                else
                    app.data_total{k} = 0;
                end
            end

            % plot current DRT peak analysis results

            plot(app.UIAxes_3, app.data{app.currentSet}(:, 1), app.data{app.currentSet}(:, 2), '-', 'Color', '#0072BD', 'LineWidth', 1)
            hold (app.UIAxes_3, 'on')
            plot(app.UIAxes_3, app.x_sep{app.currentSet}, app.y_fit{app.currentSet}, '--r', 'LineWidth', 1)
            hold (app.UIAxes_3, 'off')
            legend(app.UIAxes_3, 'DRT', 'Fitted')


            plot(app.UIAxes_4, app.data{app.currentSet}(:, 1), app.data{app.currentSet}(:, 2), '-', 'Color', '#0072BD', 'LineWidth', 1)
            hold (app.UIAxes_4, 'on')
            plot(app.UIAxes_4, app.x_sep{app.currentSet}, app.y_sep{app.currentSet}, '--r', 'LineWidth', 1)
            hold (app.UIAxes_4, 'off')
            legend(app.UIAxes_4, 'DRT', 'Separated peaks')

            % CPE calculation

            text = app.SelecttoevaluateEditField.Value;
            text(text=='-') = ':';            
            i_batchs = eval(['[', text, ']']);

            app.no_peaks = zeros(app.n_z_data, 1);
            app.Z_peak_recon = cell(app.n_z_data, 1);

            for i = 1:numel(i_batchs)
                [~, app.no_peaks(i_batchs(i))] = size(app.y_sep{i_batchs(i)});
                for j = 1:app.no_peaks(i_batchs(i))
                    [app.Z_peak_recon{i_batchs(i)}(:, j), app.CPE_fit_param{i_batchs(i)}(j, :)] = CPE_calculate(app.z_data{i_batchs(i)}(:, 1), app.x_sep{i_batchs(i)}, app.y_sep{i_batchs(i)}(:, j));
                end
            end

            [~, no_peaks] = size(app.y_sep{app.currentSet});

            for i=1:no_peaks
                app.PeaknoDropDown.Items{i} = ['Peak: ', num2str(i)];
            end

            if ismember(app.currentSet, i_batchs)
                scatter(app.UIAxes_5, real(app.Z_peak_recon{app.currentSet}(:, 1)), -imag(app.Z_peak_recon{app.currentSet}(:, 1)), 6, 'markerfacecolor', 'r', ...
                    'MarkerEdgeColor', 'r')
                hold(app.UIAxes_5, 'on')
                fplot(app.UIAxes_5, @(x) sqrt((app.CPE_fit_param{app.currentSet}(1, 3))^2 - (x-(app.CPE_fit_param{app.currentSet}(1, 1)))^2) - app.CPE_fit_param{app.currentSet}(1,2), '-r', 'linewidth', 1.5)
                scatter(app.UIAxes_5, app.CPE_fit_param{app.currentSet}(1, 1), app.CPE_fit_param{app.currentSet}(1, 2), 15, 'MarkerFaceColor', 'b', 'MarkerEdgeColor', 'b')
                axis(app.UIAxes_5, 'equal')
                legend(app.UIAxes_5, 'Calculated', 'Fitted', 'Center')
                hold(app.UIAxes_5, 'off')
    
    
                app.UITable.Data = [];
                app.UITable2.Data = [];
                
                app.DataNoDropDown_2.Value = num2str(i_batchs(1));
                app.UITable.ColumnFormat(1) = {'short'};
                app.UITable.ColumnFormat(2) = {'shortE'};
                app.UITable.ColumnFormat(1) = {'shortE'};

%                 PrintparametersandgenerateplotsButtonPushed(app, event)
                
                app.UITable.Data = [(1:length(app.data_total{i_batchs(1)}(:, 1)))', sortrows(fliplr(app.data_total{i_batchs(1)}(:, [1 3]))), (app.CPE_fit_param{i_batchs(1)}(:, 4))];
                app.UITable2.Data = [app.R_ohmic{i_batchs(1)}; 1/app.C_DRT{i_batchs(1)}];
            end

%             text(app.UIAxes_4, app.data_total{i_batchs(1)}(:, 3)+0.2, app.data_total{i_batchs(1)}(:, 2)./max(app.data_total{i_batchs(1)}(:, 2))+0.05, ...
%                arrayfun(@num2str, 1:length(app.data_total{i_batchs(1)}(:, 1)), 'UniformOutput', 0))
        end

        % Menu selected function: PeakInfoMenu
        function PeakInfoMenuSelected(app, event)
            [file,path] = uiputfile('saved_peak_info.mat','Saving peak info');
            myPeakInfo.peak_info = app.peak_info;
            myPeakInfo.check = app.check;
            save(fullfile(path,file), 'myPeakInfo');
        end

        % Menu selected function: AllPeakInfoMenu
        function AllPeakInfoMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            temp = load(fullfile(path,file));
            app.peak_info = temp.myPeakInfo.peak_info;
            app.check = temp.myPeakInfo.check;
            
            app.DRTDataNoDropDownValueChanged(true);
        end

        % Callback function
        function CurrentPeakInfoMenuSelected(app, event)
%             [file,path] = uigetfile('*.mat');
%             temp = load(fullfile(path,file));
%             app.peak_info{app.currentSet} = temp.myPeakInfo.peak_info{app.currentSet};
%             app.check
%             app.DRTDataNoDropDownValueChanged(true);
        end

        % Callback function
        function PrintparametersandgenerateplotsButtonPushed(app, event)
            if(isempty(app.n_data))
                msgbox('Please load the data first.');
            end
            clc;
            all_param = {};
            table_data = [];
            
            text_opt = app.SelecttoevaluateEditField.Value;
            text_opt(text_opt=='-') = ':';            
            i_batchs = eval(['[', text_opt, ']']);
            
            if(isempty(i_batchs))
                i_batchs = 1:(app.n_data);
            end
            
            for i_batch=i_batchs
                if(~app.check_if_optimisable(i_batch))
                    return;
                end
            end
            
            for j=1:length(i_batchs)
                fprintf('SOC : %d\n',i_batchs(j));
                saveName = [app.SaveNameEditField.Value, num2str(i_batchs(j))];
                load(fullfile('results',[saveName,'.mat']));
                all_param{j} = printModelparam(x, cand, x_opt, cand_info,y, y_orj);
                fprintf('\n\n');
            end
                                    
            for k = 1:app.n_data
                if ismember(k, i_batchs)
                    [~, idx_opt] = ismember(k, i_batchs);
                    app.DataNoDropDown_2.Items{idx_opt} = num2str(i_batchs(idx_opt));
                    app.data_1{k} = [];
                    app.data_2{k} = [];
                    app.data_3{k} = [];
                    for i = 1:3
                        if i == 3 && ~isempty(all_param{idx_opt}{3})
                            app.data_3{k} = all_param{idx_opt}{3}(:,all_param{idx_opt}{3}(1,:)~=0);
                            app.data_3{k}(3, :) = [];
                        elseif i == 2 && ~isempty(all_param{idx_opt}{2})
                            app.data_2{k} = all_param{idx_opt}{2}(:,all_param{idx_opt}{2}(1,:)~=0);
                        elseif i == 1 && ~isempty(all_param{idx_opt}{1})
                            app.data_1{k} = all_param{idx_opt}{1}(:,all_param{idx_opt}{1}(1,:)~=0);
                        end
                    end
                                    
                    app.data_total{k} = [app.data_1{k}, app.data_2{k}, app.data_3{k}];
                    app.data_total{k} = app.data_total{k}([1:2, 4], :);
                    app.data_total{k} = app.data_total{k}';
                else
                    app.data_total{k} = 0;
                end
            end
            
            app.UITable.Data = [];
            app.UITable2.Data = [];
            
            app.DataNoDropDown_2.Value = num2str(i_batchs(1));
            app.UITable.ColumnFormat(1) = {'short'};
            app.UITable.ColumnFormat(2) = {'shortE'};
            app.UITable.ColumnFormat(1) = {'shortE'};
            
            app.UITable.Data = [(1:length(app.data_total{i_batchs(1)}(:, 1)))', sortrows(fliplr(app.data_total{i_batchs(1)}(:, [1 3])))];
            app.UITable2.Data = [app.R_ohmic{i_batchs(1)}; 1/app.C_DRT{i_batchs(1)}];
            
            text(app.UIAxes, app.data_total{i_batchs(1)}(:, 3)+0.2, app.data_total{i_batchs(1)}(:, 2)./max(app.data_total{i_batchs(1)}(:, 2))+0.05, ...
               arrayfun(@num2str, 1:length(app.data_total{i_batchs(1)}(:, 1)), 'UniformOutput', 0))
       
            hold (app.UIAxes, 'off')

        end

        % Callback function
        function SelectCandidatesallButtonPushed(app, event)
            app.select_candidates(app.currentSet);
            n_peaks = length(app.peak_info{app.currentSet}.peaks.x);
            
            for i_batch=1:(app.n_data)
                n_peaks2 = length(app.peak_info{i_batch}.peaks.x);
                
                if(n_peaks2==n_peaks)
                    app.peak_info{i_batch}.indices = app.peak_info{app.currentSet}.indices;
                    app.peak_info{i_batch}.tfs =  app.peak_info{app.currentSet}.tfs;
                    app.check.is_cand_selected(i_batch) = app.check.is_cand_selected(app.currentSet);    
                end
            end
            
             msgbox(['Selected candidates are applied to all data sets with ', num2str(n_peaks),  ' peaks.']);
            
            
        end

        % Button pushed function: SelectCandidatesallButton
        function SelectCandidatesallButtonPushed2(app, event)
            app.SelectCandidatesallButtonPushed(event);
        end

        % Drop down opening function: DataNoDropDown_2
        function DataNoDropDown_2Opening(app, event)
            
        end

        % Value changed function: DataNoDropDown_2
        function DataNoDropDown_2ValueChanged(app, event)
            app.UITable.Data = [];
            app.UITable2.Data = [];
            
            value = str2num(app.DataNoDropDown_2.Value);
            app.UITable.ColumnFormat(1) = {'short'};
            app.UITable.ColumnFormat(2) = {'shortE'};
            app.UITable2.ColumnFormat(1) = {'shortE'};
            app.UITable.Data = [(1:length(app.data_total{value}(:, 1)))', sortrows(fliplr(app.data_total{value}(:, [1 3]))), (app.CPE_fit_param{value}(:, 4))];
            app.UITable2.Data = [app.R_ohmic{value}; 1/app.C_DRT{value}];
        end

        % Button pushed function: RunDRTButton
        function RunDRTButtonPushed(app, event)

            app.DRT_data = cell(app.n_z_data, 1);
            app.DRT_data = cell(app.n_z_data, 1);
            app.DRT_RL_data = cell(app.n_z_data, 1);
            app.DRT_Z_recon = cell(app.n_z_data, 1);
            app.R_ohmic = cell(app.n_z_data, 1);
            app.C_DRT = cell(app.n_z_data, 1);

            lambda = app.UITable4.Data(:, 2);
            phi_table = app.UITable3.Data(:, 2);

            for i = 1:app.n_z_data

                app.i_runDRT = 1:app.i_runDRT;
                frequency = app.z_data{i}(:, 1);
                impedance = app.z_data{i}(:, 2) - 1j.*app.z_data{i}(:, 3);

                if phi_table{i}<1
                    R_cap = -app.p_fit(i, 2)/app.p_fit(i, 1);
                    [f_min, idx_fmin] = min(app.z_data{i}(:, 1));
                    w = 2*pi*f_min;
                    C_cap = 1/abs(w)*abs((1/(impedance(idx_fmin) - R_cap))).^(1/phi_table{i});
                    impedance_DRT = impedance - 1./(1j.*2*pi*frequency*C_cap).^phi_table{i};

                    [app.DRT_data{i}(:, 2), app.DRT_data{i}(:, 1), app.DRT_RL_data{i}(:, 2), app.DRT_Z_recon{i}, app.R_ohmic{i}, app.C_DRT{i}] ...
                        = DRT_npa(impedance_DRT, frequency, phi_table{i}, lambda{i});
                    app.DRT_Z_recon{i} = app.DRT_Z_recon{i} + 1./(1j.*2*pi*frequency*C_cap).^phi_table{i};
                else
                    impedance_DRT = impedance;
                    [app.DRT_data{i}(:, 2), app.DRT_data{i}(:, 1), app.DRT_RL_data{i}(:, 2), app.DRT_Z_recon{i}, app.R_ohmic{i}, app.C_DRT{i}] ...
                        = DRT_npa(impedance_DRT, frequency, phi_table{i}, lambda{i});
                end
                                
                app.data{i} = app.DRT_data{i};
                app.norm_factor(i) = max(app.data{i}(:, 2));
                app.x_sep{i} = app.DRT_data{i}(:, 1);
                app.y_fit{i} = app.DRT_data{i}(:, 1)*0;
                app.y_sep{i} = app.DRT_data{i}(:, 1)*0;

                % normalize data for peak analysis
                maxY = max(app.data{i}(:,2));
                app.data_normalized{i}(:,2) = app.data{i}(:,2)/maxY;
%                 app.peak_info{i}.peaks.y  = app.peak_info{i}.peaks.y/maxY;
%                 app.peak_info{i}.bounds.y  = app.peak_info{i}.bounds.y/maxY;
                app.check.is_data_normalized(i) = true;
            
            app.dy2{i} = app.get_secondDerivative(i);

            end

            app.flag_runDRT = 1;
            value = str2num(app.ImpedanceDataNoDropDown.Value);

            impedance = app.z_data{value}(:, 2) - 1j.*app.z_data{value}(:, 3);
            frequency = app.z_data{value}(:, 1);

            app.h_fit{1}.XData = real(impedance);
            app.h_fit{1}.YData = -imag(impedance);

            app.h_fit{2}.XData = real(app.DRT_Z_recon{value});
            app.h_fit{2}.YData = -imag(app.DRT_Z_recon{value});
            legend(app.UIAxes2, [app.h_fit{3}, app.h_fit{2}, app.h_fit{1}], {'Measurement', 'Reconstruction', 'Fit'}, 'Location', 'northwest', 'AutoUpdate','off')

            semilogx(app.UIAxes3, frequency, 100.*(real(app.DRT_Z_recon{value} - impedance))./abs(impedance), 'Color', '#0072BD', 'LineWidth', 1)
            hold (app.UIAxes3, 'on')
            semilogx(app.UIAxes3, frequency, 100.*(imag(app.DRT_Z_recon{value} - impedance))./abs(impedance), 'Color', '#D95319', 'LineWidth', 1)
            legend(app.UIAxes3, 'Real', 'Imaginary', 'Location', 'northwest')
            hold (app.UIAxes3, 'off')
            
            app.data_normalized = app.data;

            app.n_data = length(app.data);
            app.initCheck();
            app.peak_info = cell(1, app.n_data);

            for i=1:app.n_data
                app.DRTDataNoDropDown.Items{i} = ['Dataset: ', num2str(i)];
                app.peak_info_init(i);
            end
            
            app.DRTDataNoDropDown.Items{app.n_data+1} = 'Plot all';

            app.currentSet = 1;
            app.plotCurrentSet();
            
            app.currentSet = 1;
            app.plotCurrentSet();
                
        end

        % Callback function
        function RunDRTforallCheckBoxValueChanged(app, event)
            value = app.RunDRTforallCheckBox.Value;
            if value == 1
                app.RunDRTonlyforEditField.Enable = 'off';
            else
                app.RunDRTonlyforEditField.Enable = 'on';
            end
        end

        % Menu selected function: ImportImpedanceDataMenu
        function ImportImpedanceDataMenuSelected(app, event)

            cla(app.UIAxes3)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)

            app.UITable.Data = []; % clear table
            app.UITable2.Data = []; % clear table
            app.UITable5.Data = {};
            app.flag_runDRT = 0;
            app.flag_evaluation = 0;
            app.z_data = {};

            if app.mfileButton.Value
                [file,path] = uigetfile('*.mat');
                filepath = fullfile(path, file);
                z_data_load = load(filepath);
                app.z_data = z_data_load.data;
                figure(app.DRTAnalyzerUIFigure);
            else
                [file,path] = uigetfile('*.txt', 'MultiSelect', 'on');
                for i = 1:numel(file)
                    file = cellstr(file);
                    app.file_txt{i} = [path, file{i}];
                    app.z_data{i} = biologic_read(app.file_txt{i});
                    app.z_data{i}(:, 3) = - app.z_data{i}(:, 3);
                    app.UITable5.Data{i, 1} = num2str(i); 
                    app.UITable5.Data{i, 2} = app.file_txt{i};
                    app.z_data{i} = app.z_data{i}(any(app.z_data{i}(:, 1), 2),:);  
                end
                
            end

            app.flag_runDRT = 0; % reset flag variable for running DRT
            app.n_z_data = length(app.z_data);
            app.x_sep = cell(1, app.n_z_data);
            app.y_fit = cell(1, app.n_z_data);
            app.y_sep = cell(1, app.n_z_data);
            app.Z_peak_recon = cell(1, app.n_z_data);
            app.no_peaks = [];
            app.CPE_fit_param = cell(1, app.n_z_data);

            app.p_fit = NaN(app.n_z_data, 2); % preallocate for linear fitting coefficients
            app.xy_fit = cell(app.n_z_data, 1); % preallocate for linear fitting data
            app.brush_data = cell(app.n_z_data, 1); % variable for brushdata

            app.ImpedanceDataNoDropDown.Items = {'N/A'}; % reset dropdown menu
            app.ImpedanceDataNoDropDown.Value = 'N/A'; % reset dropdown menu
            app.DRTDataNoDropDown.Items = {'N/A'}; % reset dropdown menu
            app.DRTDataNoDropDown.Value = 'N/A'; % reset dropdown menu
            app.DataNoDropDown_2.Items = {'N/A'};
            app.DataNoDropDown_2.Value = 'N/A';
            
            for i=1:app.n_z_data
                app.ImpedanceDataNoDropDown.Items{i} = num2str(i);
            end
            
            app.z_initCheck();

            hold(app.UIAxes2, 'on')

            value = str2num(app.ImpedanceDataNoDropDown.Value);
            app.h_fit{1} = plot(app.UIAxes2, nan, nan, '--k'); % fit
            app.h_fit{2} = plot(app.UIAxes2, nan, nan, '-b'); % reconstruction
            app.h_fit{3} = scatter(app.UIAxes2, app.z_data{value}(:, 2), app.z_data{value}(:, 3), 8, 'ro', 'LineWidth', 1); % meaurement data
            axis(app.UIAxes2, 'equal')
            legend(app.UIAxes2, [app.h_fit{3}, app.h_fit{2}, app.h_fit{1}], {'Measurement', 'Reconstruction', 'Fit'}, 'Location', 'northwest', 'AutoUpdate','off')

            app.UITable3.Data = [num2cell(uint8([1:app.n_z_data]')), num2cell(ones(app.n_z_data, 1))];
            app.UITable4.Data = [num2cell(uint8([1:app.n_z_data]')), num2cell(0.2.*ones(app.n_z_data, 1))];

        end

        % Value changed function: ImpedanceDataNoDropDown
        function ImpedanceDataNoDropDownValueChanged(app, event)
            value = str2num(app.ImpedanceDataNoDropDown.Value);
            if app.flag_runDRT == 1
                impedance = app.z_data{value}(:, 2) - 1j.*app.z_data{value}(:, 3);
                frequency = app.z_data{value}(:, 1);

                app.h_fit{2}.XData = real(app.DRT_Z_recon{value});
                app.h_fit{2}.YData = -imag(app.DRT_Z_recon{value});

                app.h_fit{3}.XData = real(impedance);
                app.h_fit{3}.YData = -imag(impedance);

                app.UIAxes2.Children(1).BrushData = app.brush_data{value};

                legend(app.UIAxes2, [app.h_fit{3}, app.h_fit{2}, app.h_fit{1}], {'Measurement', 'Reconstruction', 'Fit'}, 'Location', 'northwest', 'AutoUpdate','off')
                hold(app.UIAxes2, 'off')

                semilogx(app.UIAxes3, frequency, 100.*(real(app.DRT_Z_recon{value} - impedance))./abs(impedance), 'Color', '#0072BD', 'LineWidth', 1)
                hold (app.UIAxes3, 'on')
                semilogx(app.UIAxes3, frequency, 100.*(imag(app.DRT_Z_recon{value} - impedance))./abs(impedance), 'Color', '#D95319', 'LineWidth', 1)
                legend(app.UIAxes3, 'Real', 'Imaginary', 'Location', 'northwest')
                hold (app.UIAxes3, 'off')
                
            else
                impedance = app.z_data{value}(:, 2) - 1j.*app.z_data{value}(:, 3);
                app.UIAxes2.Children(1).BrushData = app.brush_data{value};
                app.h_fit{end}.XData = real(impedance);
                app.h_fit{end}.YData = -imag(impedance);
                legend(app.UIAxes2, [app.h_fit{3}, app.h_fit{2}, app.h_fit{1}], {'Measurement', 'Reconstruction', 'Fit'}, 'Location', 'northwest', 'AutoUpdate','off')
            end

            if isempty(app.xy_fit{value})
                app.h_fit{1}.XData = [];
                app.h_fit{1}.YData = [];
            else
                app.h_fit{1}.XData = app.xy_fit{value}(:, 1);
                app.h_fit{1}.YData = app.xy_fit{value}(:, 2);
            end

        end

        % Button pushed function: SaveparameterstoworkspaceButton
        function SaveparameterstoworkspaceButtonPushed(app, event)
            if app.SavealldatasetsCheckBox.Value == 1
                
                text_opt = app.SelecttoevaluateEditField.Value;
                if(isempty(text_opt))
                    i_batchs = 1:(app.n_data);
                else
                    text_opt(text_opt=='-') = ':';            
                    i_batchs = eval(['[', text_opt, ']']);
                end

                for i = 1:length(i_batchs)
                    if ismember(i, i_batchs)
                        param_export{i}.t_DRT = app.data{i}(:, 1);
                        param_export{i}.DRT = app.data{i}(:, 2);
                        param_export{i}.t_DRT_Separated_peak = app.x_sep{i};
                        param_export{i}.DRT_Separated_peak = app.y_sep{i};
                        param_export{i}.parameters.RC = sortrows(fliplr(app.data_total{i}));
                        param_export{i}.parameters.R_ohm = app.R_ohmic{i};
                        param_export{i}.parameters.capacitance = 1/app.C_DRT{i};
                    else
                        param_export{i} = {};
                    end
                end
            else
                param_idx = str2double(app.DataNoDropDown_2.Value);
                param_export{1}.t_DRT = app.data{param_idx}(:, 1);
                param_export{1}.DRT = app.data{param_idx}(:, 2);
                param_export{1}.t_DRT_Separated_peak = app.x_sep{param_idx};
                param_export{1}.DRT_Separated_peak = app.y_sep{param_idx};
                param_export{1}.CPE_exponent = app.CPE_fit_param{param_idx}(:, 4);
                param_export{1}.parameters.RC = sortrows(fliplr(app.data_total{param_idx}));
                param_export{1}.parameters.R_ohm = app.R_ohmic{param_idx};
                param_export{1}.parameters.capacitance = 1/app.C_DRT{param_idx};
            end

            var_name = app.VariablenameEditField.Value;
            assignin('base', var_name, param_export)
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            value = str2num(app.ImpedanceDataNoDropDown.Value);
            impedance_data = [app.z_data{value}(:, 2), app.z_data{value}(:, 3)];

            hold(app.UIAxes2, 'on')

            n_line = length(app.UIAxes2.Children);
            data_brush = logical(app.UIAxes2.Children(1).BrushData);

            data_fit = impedance_data(data_brush, :);
            app.p_fit(value, :) = polyfit(data_fit(:, 1), data_fit(:, 2), 1);
            phi = atan(app.p_fit(value, 1))/(pi/2);

            y_lim = ylim(app.UIAxes2);
            x_lim = (y_lim - app.p_fit(value, 2))./app.p_fit(value, 1);

            x_fit = linspace(x_lim(1), x_lim(2), 100);
            y_fit = app.p_fit(value, 1).*x_fit + app.p_fit(value, 2);

            app.xy_fit{value} = [x_fit', y_fit'];

            app.h_fit{1}.XData = x_fit;
            app.h_fit{1}.YData = y_fit;

            if app.ApplytoallCheckBox.Value
                app.UITable3.Data(:, 2) = num2cell(phi.*ones(app.n_z_data, 1));
            else
                app.UITable3.Data(value, 2) = num2cell(phi);
            end

        end

        % Button pushed function: startchoosingButton
        function startchoosingButtonPushed(app, event)
            brush(app.UIAxes2, 'on');
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            value = str2num(app.ImpedanceDataNoDropDown.Value);
            brush(app.UIAxes2, 'off');
            app.brush_data{value} = app.UIAxes2.Children(1).BrushData;
        end

        % Button pushed function: ClearfittingButton
        function ClearfittingButtonPushed(app, event)

            value = str2num(app.ImpedanceDataNoDropDown.Value);
            app.UIAxes2.Children(1).BrushData = [];
            app.UIAxes2.Children(2).BrushData = [];
            app.h_fit{1}.XData = [];
            app.h_fit{1}.YData = [];

            if app.ClearallCheckBox.Value
                app.p_fit = NaN(app.n_z_data, 2); % preallocate for linear fitting coefficients
                app.xy_fit = cell(app.n_z_data, 1); % preallocate for linear fitting data
                app.brush_data = cell(app.n_z_data, 1);
            else
                app.p_fit(value, :) = NaN(1, 2); % preallocate for linear fitting coefficients
                app.xy_fit(value, 1) = cell(1, 1); % preallocate for linear fitting data
                app.brush_data{value} = cell(1, 1);
            end
        end

        % Value changed function: PeaknoDropDown
        function PeaknoDropDownValueChanged(app, event)
            value = app.PeaknoDropDown.Value;
            value = str2num(value(end));
            scatter(app.UIAxes_5, real(app.Z_peak_recon{app.currentSet, 1}(:, value)), -imag(app.Z_peak_recon{app.currentSet, 1}(:, value)), 6, 'markerfacecolor', 'r', ...
                'MarkerEdgeColor', 'r')
            hold(app.UIAxes_5, 'on')
            fplot(app.UIAxes_5, @(x) sqrt((app.CPE_fit_param{app.currentSet}(value, 3))^2 - (x-(app.CPE_fit_param{app.currentSet}(value, 1)))^2) - app.CPE_fit_param{app.currentSet}(value,2), '-r', 'linewidth', 1.5)
            scatter(app.UIAxes_5, app.CPE_fit_param{app.currentSet}(value, 1), -app.CPE_fit_param{app.currentSet}(value, 2), 20, 'markerfacecolor', 'b', 'MarkerEdgeColor', 'b')
            legend(app.UIAxes_5, 'Caculated', 'Fitted', 'Center')
            axis(app.UIAxes_5, 'equal') 
            xlim(app.UIAxes_5, 'auto')
            hold(app.UIAxes_5, 'off')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DRTAnalyzerUIFigure = uifigure('Visible', 'off');
            app.DRTAnalyzerUIFigure.Name = 'DRT Analyzer';
            app.DRTAnalyzerUIFigure.Icon = 'DRT_analyzer_icon.png';
            app.DRTAnalyzerUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @DRTAnalyzerUIFigureWindowButtonDown, true);
            app.FileMenu = uimenu(app.DRTAnalyzerUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportImpedanceDataMenu = uimenu(app.FileMenu);
            app.ImportImpedanceDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImpedanceDataMenuSelected, true);
            app.ImportImpedanceDataMenu.Text = 'Import Impedance Data';
            app.ImportDRTDataMenu = uimenu(app.FileMenu);
            app.ImportDRTDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportDRTDataMenuSelected, true);
            app.ImportDRTDataMenu.Text = 'Import DRT Data';
            app.SaveLoadPeakInfoMenu = uimenu(app.DRTAnalyzerUIFigure);
            app.SaveLoadPeakInfoMenu.Text = 'Save/Load Peak Info';
            app.SaveMenu_2 = uimenu(app.SaveLoadPeakInfoMenu);
            app.SaveMenu_2.Text = 'Save';
            app.PeakInfoMenu = uimenu(app.SaveMenu_2);
            app.PeakInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @PeakInfoMenuSelected, true);
            app.PeakInfoMenu.Text = 'Peak Info';
            app.LoadMenu = uimenu(app.SaveLoadPeakInfoMenu);
            app.LoadMenu.Text = 'Load';
            app.AllPeakInfoMenu = uimenu(app.LoadMenu);
            app.AllPeakInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @AllPeakInfoMenuSelected, true);
            app.AllPeakInfoMenu.Text = 'All Peak Info';
            app.DRTAnalysisPanel = uipanel(app.DRTAnalyzerUIFigure);
            app.DRTAnalysisPanel.Title = 'DRT Analysis';
            app.TabGroup2 = uitabgroup(app.DRTAnalysisPanel);
            app.DRTTab = uitab(app.TabGroup2);
            app.DRTTab.Title = 'DRT';
            app.RunDRTButton = uibutton(app.DRTTab, 'push');
            app.RunDRTButton.ButtonPushedFcn = createCallbackFcn(app, @RunDRTButtonPushed, true);
            app.RunDRTButton.Text = 'Run DRT';
            app.ChoosedataforcalculationTab = uitab(app.TabGroup2);
            app.ChoosedataforcalculationTab.Title = 'Choose data for ϕ calculation';
            app.startchoosingButton = uibutton(app.ChoosedataforcalculationTab, 'push');
            app.startchoosingButton.ButtonPushedFcn = createCallbackFcn(app, @startchoosingButtonPushed, true);
            app.startchoosingButton.Text = 'start choosing';
            app.FinishButton = uibutton(app.ChoosedataforcalculationTab, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.ClearallCheckBox = uicheckbox(app.ChoosedataforcalculationTab);
            app.ClearallCheckBox.Text = 'Clear all';
            app.ApplytoallCheckBox = uicheckbox(app.ChoosedataforcalculationTab);
            app.ApplytoallCheckBox.Text = 'Apply to all';
            app.CalculateButton = uibutton(app.ChoosedataforcalculationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate ϕ';
            app.ClearfittingButton = uibutton(app.ChoosedataforcalculationTab, 'push');
            app.ClearfittingButton.ButtonPushedFcn = createCallbackFcn(app, @ClearfittingButtonPushed, true);
            app.ClearfittingButton.Text = 'Clear fitting';
            app.ImpedanceDataNoDropDownLabel = uilabel(app.DRTAnalysisPanel);
            app.ImpedanceDataNoDropDownLabel.Text = 'Impedance Data No:';
            app.ImpedanceDataNoDropDown = uidropdown(app.DRTAnalysisPanel);
            app.ImpedanceDataNoDropDown.Items = {'N/A'};
            app.ImpedanceDataNoDropDown.ValueChangedFcn = createCallbackFcn(app, @ImpedanceDataNoDropDownValueChanged, true);
            app.ImpedanceDataNoDropDown.Value = 'N/A';
            app.TabGroup5 = uitabgroup(app.DRTAnalysisPanel);
            app.LoadedfilesTab = uitab(app.TabGroup5);
            app.LoadedfilesTab.Title = 'Loaded files';
            app.UITable5 = uitable(app.LoadedfilesTab);
            app.UITable5.ColumnName = {'Number'; 'File'};
            app.UITable5.RowName = {};
            app.FileloadingoptionButtonGroup = uibuttongroup(app.LoadedfilesTab);
            app.FileloadingoptionButtonGroup.Title = 'File loading option';
            app.mfileButton = uiradiobutton(app.FileloadingoptionButtonGroup);
            app.mfileButton.Text = '.m file';
            app.mfileButton.Value = true;
            app.BiologictxtButton = uiradiobutton(app.FileloadingoptionButtonGroup);
            app.BiologictxtButton.Text = 'Biologic .txt';
            app.ImpedanceTab = uitab(app.TabGroup5);
            app.ImpedanceTab.Title = 'Impedance';
            app.UIAxes2 = uiaxes(app.ImpedanceTab);
            title(app.UIAxes2, 'Impedance')
            xlabel(app.UIAxes2, 'Re(Z) [Ω] ')
            ylabel(app.UIAxes2, '-Im(Z) [Ω]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.ImpedanceTab);
            title(app.UIAxes3, 'DRT Reconstruction Error')
            xlabel(app.UIAxes3, 'f [Hz]')
            ylabel(app.UIAxes3, 'Δ [%]')
            zlabel(app.UIAxes3, 'Z')
            app.SetvalueTab_2 = uitab(app.TabGroup5);
            app.SetvalueTab_2.Title = 'Set λ value';
            app.ThevalueisusedtocontroltheregularizationeffectLabel = uilabel(app.SetvalueTab_2);
            app.ThevalueisusedtocontroltheregularizationeffectLabel.Interpreter = 'latex';
            app.ThevalueisusedtocontroltheregularizationeffectLabel.Text = {'The λ value is used to control the regularization effect:'; ''};
            app.Label = uilabel(app.SetvalueTab_2);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '$x=\mathrm{argmin}: \left\Vert Ax-b \right\Vert^2 + \lambda^2 \left\Vert x \right\Vert^2$';
            app.Label_2 = uilabel(app.SetvalueTab_2);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = {'Large \lambda leads to depressed spectrum and less peaks,'; ' small \lambda leads to more clearly separated peaks, but '; 'may be subjected to noise'};
            app.UITable4 = uitable(app.SetvalueTab_2);
            app.UITable4.ColumnName = {'Data set'; 'λ value'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnEditable = [false true];
            app.SetvalueTab = uitab(app.TabGroup5);
            app.SetvalueTab.Title = 'Set ϕ value';
            app.ThephiLabel = uilabel(app.SetvalueTab);
            app.ThephiLabel.Interpreter = 'latex';
            app.ThephiLabel.Text = {'The $\phi$ value is used to characterize the impact of the'; ' fractal diffusion on the cell capacitance when'; 'conducting DRT analysis, the default value of $\phi$  is 1:'};
            app.Z_capfrac1jomegaCphiLabel = uilabel(app.SetvalueTab);
            app.Z_capfrac1jomegaCphiLabel.Interpreter = 'latex';
            app.Z_capfrac1jomegaCphiLabel.Text = '$Z_{cap}=\frac{1}{{(j\omega C)}^{\phi}}$';
            app.UITable3 = uitable(app.SetvalueTab);
            app.UITable3.ColumnName = {'Data set'; 'ϕ Value'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = [false true];
            app.TheLabel = uilabel(app.SetvalueTab);
            app.TheLabel.Interpreter = 'latex';
            app.TheLabel.Text = {'The ϕ value can either be manually set or calculated by'; ' using"Calculate ϕ" function. After calculation, the'; ' ϕ value in the following table will be automatically '; 'updated.'};
            app.PeakAnalysisPanel = uipanel(app.DRTAnalyzerUIFigure);
            app.PeakAnalysisPanel.Title = 'Peak Analysis';
            app.TabGroup4 = uitabgroup(app.PeakAnalysisPanel);
            app.ParameterexportTab = uitab(app.TabGroup4);
            app.ParameterexportTab.Title = 'Parameter export';
            app.VariablenameLabel = uilabel(app.ParameterexportTab);
            app.VariablenameLabel.Text = 'Variable name: ';
            app.VariablenameEditField = uieditfield(app.ParameterexportTab, 'text');
            app.VariablenameEditField.Value = 'param_saved';
            app.SaveparameterstoworkspaceButton = uibutton(app.ParameterexportTab, 'push');
            app.SaveparameterstoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparameterstoworkspaceButtonPushed, true);
            app.SaveparameterstoworkspaceButton.Text = {'Save parameters'; 'to workspace'};
            app.SavealldatasetsCheckBox = uicheckbox(app.ParameterexportTab);
            app.SavealldatasetsCheckBox.Text = 'Save all data sets';
            app.DRTDataNoDropDownLabel = uilabel(app.PeakAnalysisPanel);
            app.DRTDataNoDropDownLabel.Text = 'DRT Data No:';
            app.DRTDataNoDropDown = uidropdown(app.PeakAnalysisPanel);
            app.DRTDataNoDropDown.Items = {'N/A'};
            app.DRTDataNoDropDown.ValueChangedFcn = createCallbackFcn(app, @DRTDataNoDropDownValueChanged, true);
            app.DRTDataNoDropDown.Value = 'N/A';
            app.TabGroup = uitabgroup(app.PeakAnalysisPanel);
            app.PeakanalysispreprocessingTab = uitab(app.TabGroup);
            app.PeakanalysispreprocessingTab.Title = 'Peak analysis preprocessing';
            app.AddpeaksButton = uibutton(app.PeakanalysispreprocessingTab, 'state');
            app.AddpeaksButton.ValueChangedFcn = createCallbackFcn(app, @AddpeaksButtonValueChanged, true);
            app.AddpeaksButton.Visible = 'off';
            app.AddpeaksButton.Text = 'Add peaks';
            app.DeletepeaksButton = uibutton(app.PeakanalysispreprocessingTab, 'state');
            app.DeletepeaksButton.ValueChangedFcn = createCallbackFcn(app, @DeletepeaksButtonValueChanged, true);
            app.DeletepeaksButton.Visible = 'off';
            app.DeletepeaksButton.Text = 'Delete peaks';
            app.AddboundsButton = uibutton(app.PeakanalysispreprocessingTab, 'state');
            app.AddboundsButton.ValueChangedFcn = createCallbackFcn(app, @AddboundsButtonValueChanged, true);
            app.AddboundsButton.Visible = 'off';
            app.AddboundsButton.Text = 'Add bounds';
            app.DeleteboundsButton = uibutton(app.PeakanalysispreprocessingTab, 'state');
            app.DeleteboundsButton.ValueChangedFcn = createCallbackFcn(app, @DeleteboundsButtonValueChanged, true);
            app.DeleteboundsButton.Visible = 'off';
            app.DeleteboundsButton.Text = 'Delete bounds';
            app.SelectCandidatesButton = uibutton(app.PeakanalysispreprocessingTab, 'push');
            app.SelectCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCandidatesButtonPushed, true);
            app.SelectCandidatesButton.Visible = 'off';
            app.SelectCandidatesButton.Text = 'Select Candidates';
            app.ChangepeaksLabel = uilabel(app.PeakanalysispreprocessingTab);
            app.ChangepeaksLabel.Visible = 'off';
            app.ChangepeaksLabel.Text = 'Change peaks:';
            app.ChangeboundsLabel = uilabel(app.PeakanalysispreprocessingTab);
            app.ChangeboundsLabel.Visible = 'off';
            app.ChangeboundsLabel.Text = 'Change bounds:';
            app.SwitchtopeakviewButton = uibutton(app.PeakanalysispreprocessingTab, 'state');
            app.SwitchtopeakviewButton.ValueChangedFcn = createCallbackFcn(app, @SwitchtopeakviewButtonValueChanged, true);
            app.SwitchtopeakviewButton.Text = {'Switch to '; 'peak'; 'view'};
            app.SelectCandidatesallButton = uibutton(app.PeakanalysispreprocessingTab, 'push');
            app.SelectCandidatesallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCandidatesallButtonPushed2, true);
            app.SelectCandidatesallButton.Visible = 'off';
            app.SelectCandidatesallButton.Text = {'Select Candidates'; '(all)'};
            app.OptimisationsettingTab = uitab(app.TabGroup);
            app.OptimisationsettingTab.Title = 'Optimisation setting';
            app.SaveNameLabel = uilabel(app.OptimisationsettingTab);
            app.SaveNameLabel.Text = 'Save Name:';
            app.SaveNameEditField = uieditfield(app.OptimisationsettingTab, 'text');
            app.SaveNameEditField.Value = 'savedresults';
            app.EvaluationtimelimitsLabel = uilabel(app.OptimisationsettingTab);
            app.EvaluationtimelimitsLabel.Text = 'Evaluation time limit (s)';
            app.EvaluationtimelimitsEditField = uieditfield(app.OptimisationsettingTab, 'numeric');
            app.EvaluationtimelimitsEditField.Limits = [1 Inf];
            app.EvaluationtimelimitsEditField.Value = 1800;
            app.EvaluateselectedButton = uibutton(app.OptimisationsettingTab, 'push');
            app.EvaluateselectedButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateselectedButtonPushed, true);
            app.EvaluateselectedButton.Text = 'Evaluate selected ';
            app.SelecttooptimizeLabel = uilabel(app.OptimisationsettingTab);
            app.SelecttooptimizeLabel.Text = 'Select to evaluate:';
            app.SelecttoevaluateEditField = uieditfield(app.OptimisationsettingTab, 'text');
            app.SelecttoevaluateEditField.Value = '1-3, 5-7';
            app.GenerateandsaveseparatefiguresCheckBox = uicheckbox(app.OptimisationsettingTab);
            app.GenerateandsaveseparatefiguresCheckBox.Text = 'Generate and save separate figures';
            app.GenerateandsaveseparatefiguresCheckBox.Value = true;
            app.UseparallelcomputingCheckBox = uicheckbox(app.OptimisationsettingTab);
            app.UseparallelcomputingCheckBox.Text = 'Use parallel computing';
            app.MethodLabel = uilabel(app.OptimisationsettingTab);
            app.MethodLabel.Text = 'Method:';
            app.MethodDropDown = uidropdown(app.OptimisationsettingTab);
            app.MethodDropDown.Items = {'Enumeration: fmincon', 'Branch and Bound', 'GlobalSearch', 'MultiSearch: lsqnonlin', 'Particle Swarm Optimisation', 'Genetic Algorithm'};
            app.MethodDropDown.Value = 'Enumeration: fmincon';
            app.DataNoDropDown_2Label = uilabel(app.PeakAnalysisPanel);
            app.DataNoDropDown_2Label.Text = 'Data No: ';
            app.DataNoDropDown_2 = uidropdown(app.PeakAnalysisPanel);
            app.DataNoDropDown_2.Items = {'N/A'};
            app.DataNoDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DataNoDropDown_2Opening, true);
            app.DataNoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DataNoDropDown_2ValueChanged, true);
            app.DataNoDropDown_2.Value = 'N/A';
            app.TabGroup3 = uitabgroup(app.PeakAnalysisPanel);
            app.PeakparametersTab = uitab(app.TabGroup3);
            app.PeakparametersTab.Title = 'Peak parameters';
            app.UITable = uitable(app.PeakparametersTab);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.PeakLabel = uilabel(app.PeakparametersTab);
            app.PeakLabel.Interpreter = 'latex';
            app.PeakLabel.Text = 'Peak';
            app.log_10tauLabel = uilabel(app.PeakparametersTab);
            app.log_10tauLabel.Interpreter = 'latex';
            app.log_10tauLabel.Text = '$\log_{10}(\tau)$';
            app.RLabel = uilabel(app.PeakparametersTab);
            app.RLabel.Interpreter = 'latex';
            app.RLabel.Text = 'R';
            app.nLabel = uilabel(app.PeakparametersTab);
            app.nLabel.Interpreter = 'latex';
            app.nLabel.Text = 'n';
            app.OtherparametersTab = uitab(app.TabGroup3);
            app.OtherparametersTab.Title = 'Other parameters';
            app.UITable2 = uitable(app.OtherparametersTab);
            app.UITable2.ColumnName = {''; ''};
            app.UITable2.RowName = {'Ohmic resistance [Ω]'; 'Capacitance [F]'};
            app.ParameterLabel = uilabel(app.OtherparametersTab);
            app.ParameterLabel.Interpreter = 'latex';
            app.ParameterLabel.Text = 'Parameter';
            app.ValueLabel = uilabel(app.OtherparametersTab);
            app.ValueLabel.Interpreter = 'latex';
            app.ValueLabel.Text = 'Value';
            app.TabGroup6 = uitabgroup(app.PeakAnalysisPanel);
            app.DRTrawTab = uitab(app.TabGroup6);
            app.DRTrawTab.Title = 'DRT - raw';
            app.UIAxes = uiaxes(app.DRTrawTab);
            title(app.UIAxes, 'DRT')
            ylabel(app.UIAxes, 'G(\tau)')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.DRTrawTab);
            title(app.UIAxes_2, 'Second Derivative')
            xlabel(app.UIAxes_2, 'log_{10}(\tau)')
            ylabel(app.UIAxes_2, 'd^2G(\tau)')
            app.DRTevaluatedTab = uitab(app.TabGroup6);
            app.DRTevaluatedTab.Title = 'DRT - evaluated';
            app.UIAxes_3 = uiaxes(app.DRTevaluatedTab);
            title(app.UIAxes_3, 'DRT-Fitted')
            ylabel(app.UIAxes_3, 'G(\tau)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.DRTevaluatedTab);
            title(app.UIAxes_4, 'DRT-Separated peaks')
            xlabel(app.UIAxes_4, 'log_{10}(\tau)')
            ylabel(app.UIAxes_4, 'G(\tau)')
            zlabel(app.UIAxes_4, 'Z')
            app.DRTCPEexponentTab = uitab(app.TabGroup6);
            app.DRTCPEexponentTab.Title = 'DRT - CPE exponent';
            app.UIAxes_5 = uiaxes(app.DRTCPEexponentTab);
            title(app.UIAxes_5, 'CPE calculation')
            xlabel(app.UIAxes_5, 'Z''')
            ylabel(app.UIAxes_5, '-Z''''')
            zlabel(app.UIAxes_5, 'Z')
            app.PeaknoDropDownLabel = uilabel(app.DRTCPEexponentTab);
            app.PeaknoDropDownLabel.Text = 'Peak no:';
            app.PeaknoDropDown = uidropdown(app.DRTCPEexponentTab);
            app.PeaknoDropDown.Items = {'N/A'};
            app.PeaknoDropDown.ValueChangedFcn = createCallbackFcn(app, @PeaknoDropDownValueChanged, true);
            app.PeaknoDropDown.Value = 'N/A';
            app.ContextMenu = uicontextmenu(app.DRTAnalyzerUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.DRTAnalyzerUIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.ImpedanceDataNoDropDown.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.DRTAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DRTanalyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DRTAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DRTAnalyzerUIFigure)
        end
    end
end